classdef PROJEKT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        SingleplayerTab             matlab.ui.container.Tab
        VelocitySliderLabel         matlab.ui.control.Label
        VelocitySlider              matlab.ui.control.Slider
        AngleSliderLabel            matlab.ui.control.Label
        AngleSlider                 matlab.ui.control.Slider
        EnvironmentDropDownLabel    matlab.ui.control.Label
        EnvironmentDropDown         matlab.ui.control.DropDown
        FIREButton                  matlab.ui.control.Button
        PicknewpointButton          matlab.ui.control.Button
        HITLampLabel                matlab.ui.control.Label
        HITLamp                     matlab.ui.control.Lamp
        UIAxes                      matlab.ui.control.UIAxes
        Background1                 matlab.ui.control.Image
        TriesEditFieldLabel         matlab.ui.control.Label
        TriesEditField              matlab.ui.control.NumericEditField
        TwoplayersTab               matlab.ui.container.Tab
        UIAxes2                     matlab.ui.control.UIAxes
        EnvironmentDropDown_2Label  matlab.ui.control.Label
        EnvironmentDropDown_2       matlab.ui.control.DropDown
        Player1Panel                matlab.ui.container.Panel
        FIREButton_2                matlab.ui.control.Button
        VelocitySlider_2Label       matlab.ui.control.Label
        VelocitySlider_2            matlab.ui.control.Slider
        AngleSlider_2Label          matlab.ui.control.Label
        AngleSlider_2               matlab.ui.control.Slider
        Player2Panel                matlab.ui.container.Panel
        VelocitySlider_3Label       matlab.ui.control.Label
        VelocitySlider_3            matlab.ui.control.Slider
        AngleSlider_3Label          matlab.ui.control.Label
        AngleSlider_3               matlab.ui.control.Slider
        FIREButton_3                matlab.ui.control.Button
        TextArea_2                  matlab.ui.control.TextArea
        LifeGaugeLabel              matlab.ui.control.Label
        LifeGauge                   matlab.ui.control.SemicircularGauge
        LifeGauge_2Label            matlab.ui.control.Label
        LifeGauge_2                 matlab.ui.control.SemicircularGauge
        RulesTab                    matlab.ui.container.Tab
        TextArea                    matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        %%współrzędne wylosowanego punktu
        PointX=0;
        PointY=0;
        
        % punkty dla trybu dwuosobowego
        PointX2=0;
        PointY2=0;
        
        isclickedPeaknewpointButton=0;
        grav=9.81;
        I; % image - plot background
        
        
        bonus=0; % bonus do obrazen
        
        runda = 0; % runda
    end
    
    methods (Access = public)
        
        
        % Oblicza pozycje ze wzoru na rzut ukosny
        function position = CompPos(~, v, g, a, x)
        % v - velocity
        % g - gravitational constant
        % a - angle
        % x - vector of position
            position=x.*( tand(a) - g.*x./(2*v^2 *(cosd(a))^2) );
        end
        
        % losuje punkty z przedzialu z pierscienia kwadratowego 
        % [(0,0), 2, 9]
        function NewPoint(app)
            app.TriesEditField.Value=0;
            app.PointX=18*rand-9;
            app.PointY=18*rand-9;
            % wybiera nowy punkt i sprawdza czy zachodzi ponizszy warunek
            while ( abs(app.PointX)<2 && abs(app.PointY) <2 || app.PointX*app.PointY==0)
                %sprawdza czy punkty nie są zbyt blisko punktu (0,0) i osXY
                app.PointX=18*rand-9;
                app.PointY=18*rand-9;
            end
        end
        
        % funkcja jak wyzej dla trybu dwuosobowego
        function NewPoint2(app)
            app.PointX2=15*rand-7.5;
            app.PointY2=15*rand-7.5;
            % wybiera nowy punkt i sprawdza czy zachodzi ponizszy warunek
            if app.grav > 0
                while ( abs(app.PointX2)<2 && app.PointY2 < 2 || app.PointX2*app.PointY2==0)
                %sprawdza czy punkty nie są zbyt blisko punktu (0,0) i osXY
                    app.PointX2=15*rand-7.5;
                    app.PointY2=15*rand-7.5;
                end
            else
                while ( abs(app.PointX2)<2 && app.PointY2 > -2 || app.PointX2*app.PointY2==0)
                %sprawdza czy punkty nie są zbyt blisko punktu (0,0) i osXY
                    app.PointX2=15*rand-7.5;
                    app.PointY2=15*rand-7.5;
                end
            end
        end
        
        % funkcja obliczajaca bonus z odleglosci od jakiej postrzelono
        % gracza
        function result = CompBonus(~, d)
            if d < .4
                result = 2;
            end
            if d < .3
                result = 4;
            end
            if d < .2
                result = 6;
            end
            if d < .1
                result = 10;
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            movegui(app.UIFigure, 'center');
            app.UIAxes.Color='none';
        end

        % Button pushed function: FIREButton
        function FIREButtonPushed(app, event)
        
            % pobranie kąta i prędkości
            angle=app.AngleSlider.Value;
            velocity=app.VelocitySlider.Value;
            app.HITLamp.Color='w';
            
            
            if( mod(angle, 180) >80 && mod(angle, 180) <100)
                coile=.05;
            else
                coile=.3;
            end
            q=1; % znak toru lotu (prawo/lewo)
            if (angle>90 && angle<270)
                q=-1;
            end
            if( app.PointX==0 && app.PointY==0) % sprawdzenie czy punkty zostaly wylosowane
               NewPoint(app);
            end
            if( app.isclickedPeaknewpointButton==1 )
                NewPoint(app);
            end
            p=plot(app.UIAxes, app.PointX, app.PointY, 'g*', 0, 0, 'rd'); %rysuje punkt
            p(2).MarkerSize=12;
            hold(app.UIAxes, 'on');
            app.UIAxes.XLim = [-10 10];
            app.UIAxes.YLim = [-10 10]; %granice wykresu
            
            
            % rysowanie trajektorii lotu
            if(app.isclickedPeaknewpointButton==0)
                for X=0:coile:10
                    X=q*X;
                    Y=CompPos(app, velocity, app.grav, angle, X);
                    if (abs(Y)>12)
                        break
                    end
                    plot(app.UIAxes, X, Y, 'r.');
                    pause(1/200);
                end
                
                 % kolorowanie lampy - czy trafiono w punkt
            
                if( abs( app.PointY-CompPos(app, velocity, app.grav, angle, app.PointX) ) < .25 )
                    app.HITLamp.Color='g';
                    app.FIREButton.Enable='off';
                else
                    app.HITLamp.Color='r';
                    app.TriesEditField.Value=app.TriesEditField.Value+1;
                end
            end
            
            hold(app.UIAxes, 'off');
            app.isclickedPeaknewpointButton=0;


        end

        % Button pushed function: PicknewpointButton
        function PicknewpointButtonPushed(app, event)
            app.isclickedPeaknewpointButton=1;
            app.FIREButton.Enable='on';
            app.FIREButton.Text='FIRE!'
            FIREButtonPushed(app, event);
        end

        % Value changed function: EnvironmentDropDown
        function EnvironmentDropDownValueChanged(app, event)
            value = app.EnvironmentDropDown.Value;
            app.UIAxes.Alphamap=.5;
            switch value
                case 'Land' 
                    app.grav=9.81;
                    app.Background1.ImageSource='landsurfc.jpg';
                case 'Underwater' 
                    app.grav=-9.81;
                    app.Background1.ImageSource='underwatersurf.jpg';
                case 'The Moon'
                    app.grav=9.81/6;
                    app.Background1.ImageSource='moonsurf.jpg';
                case 'Void'
                    app.grav=0;
                    app.Background1.ImageSource='void.jpg';
            end
            
            
        end

        % Button pushed function: FIREButton_2
        function FIREButton_2Pushed(app, event)
            % pobranie kąta i prędkości gracza 1
            angle=app.AngleSlider_2.Value;
            velocity=app.VelocitySlider_2.Value;
            
            if(angle<80)
                coile=.3;
            else
                coile=.1;
            end


            plot(app.UIAxes2, app.PointX2, app.PointY2, 'g*'); %rysuje punkt
            hold(app.UIAxes2, 'on');
            p=plot(app.UIAxes2, -8, 0, 'rd', 8, 0, 'bd');
            p(1).MarkerSize=12;
            p(2).MarkerSize=12;
            % x-owe wspolrzedne sa przesuniete o 8 w lewo
            
            app.UIAxes2.XLim = [-10 10];
            app.UIAxes2.YLim = [-10 10]; %granice wykresu
            
            
            % rysowanie trajektorii lotu
            for X=-8:coile:10
                Y=CompPos(app, velocity, app.grav, angle, X+8);
                if (abs(Y)>12)
                   break
                end
                plot(app.UIAxes2, X, Y, 'r*');
                pause(1/200);
            end
                
            % kolorowanie lampy - czy trafiono w punkt
            
            if( abs( app.PointY2-CompPos(app, velocity, app.grav, angle, app.PointX2+8) ) < .3 )
                app.bonus=2;
            end
            
            hold(app.UIAxes2, 'off');
            
            % utrata punktow zycia
            
            dist=abs(0-CompPos(app, velocity, app.grav, angle, 16));
            if (dist <.4)
                b=CompBonus(app, dist);
                minus=round(b*rand+2) + 5*app.bonus;
                for i=0:1:minus
                    app.LifeGauge_2.Value=app.LifeGauge_2.Value - 1;
                    pause(1/5);
                end
            
            end
            app.bonus=0;
            
            app.Player1Panel.Visible='off';
            
            % zaklocenia, zeby gra nie byla monotonna - od 3 rundy
            if app.runda > 2
                app.VelocitySlider_2.Value = 1;%app.VelocitySlider_2.Value + 6*round(rand-.5);
                app.AngleSlider_2.Value = 1;%app.AngleSlider_2.Value + 6*round(rand-.5);
            end
            
            if app.LifeGauge_2.Value < 1
                app.TextArea_2.Visible='on';
                app.TextArea_2.Value='Player 1 won!';
            else
                pause(3);
                app.Player2Panel.Visible='on';
                NewPoint2(app);
                plot(app.UIAxes2, app.PointX2, app.PointY2, 'g*');
                hold(app.UIAxes2, 'on');
                p=plot(app.UIAxes2, -8, 0, 'rd', 8, 0, 'bd');
                p(1).MarkerSize=12;
                p(2).MarkerSize=12;
                hold(app.UIAxes2, 'off');
            end
        end

        % Button pushed function: FIREButton_3
        function FIREButton_3Pushed(app, event)
            % pobranie kąta i prędkości gracza 2
            angle=-app.AngleSlider_3.Value;
            velocity=app.VelocitySlider_3.Value;
            
            app.runda=app.runda+1;
            
            if( angle<80)
                coile=.3;
            else
                coile=.1;
            end


            plot(app.UIAxes2, app.PointX2, app.PointY2, 'g*'); %rysuje punkt
            hold(app.UIAxes2, 'on');
            p=plot(app.UIAxes2, -8, 0, 'rd', 8, 0, 'bd');
            p(1).MarkerSize=12;
            p(2).MarkerSize=12;
            % x-owe wspolrzedne sa przesuniete o 8 w prawo
            app.UIAxes2.XLim = [-10 10];
            app.UIAxes2.YLim = [-10 10]; %granice wykresu
            
            
            % rysowanie trajektorii lotu
            for X=-8:coile:10
                % odwracamy macierz X, bo chcemy ruch w lewo
                Y=CompPos(app, velocity, app.grav, angle, -X-8);
                if (abs(Y)>12)
                   break
                end
                plot(app.UIAxes2, -X, Y, 'b*');
                pause(1/200);
            end
                
            % kolorowanie lampy - czy trafiono w punkt
            
            if( abs( app.PointY2-CompPos(app, velocity, app.grav, angle, app.PointX2) ) < .3 )
                app.bonus=2;
            end
            
            hold(app.UIAxes2, 'off');
            
            % utrata punktow zycia
            dist=abs(0-CompPos(app, velocity, app.grav, angle, -16));
            if (dist <.4)
                b=CompBonus(app, dist);
                minus=round(b*rand+2) + 5*app.bonus;
                for i=0:1:minus
                    app.LifeGauge.Value=app.LifeGauge.Value - 1;
                    pause(1/5);
                end
            end
            app.bonus=0;
            
            app.Player2Panel.Visible='off';
            
            % zaklocenia, zeby gra nie byla monotonna - od 3 rundy
            if app.runda > 2
                app.VelocitySlider_3.Value = 1;%app.VelocitySlider_3.Value + 6*round(rand-.5);
                app.AngleSlider_3.Value = 1;%app.AngleSlider_3.Value + 6*round(rand-.5);
            end
            
            
            if app.LifeGauge.Value < 1
                app.TextArea_2.Visible='on';
                app.TextArea_2.Value='Player 2 won!';
            else
                pause(3);
                app.Player1Panel.Visible='on';
                NewPoint2(app);
                plot(app.UIAxes2, app.PointX2, app.PointY2, 'g*');
                hold(app.UIAxes2, 'on');
                p=plot(app.UIAxes2, -8, 0, 'rd', 8, 0, 'bd');
                p(1).MarkerSize=12;
                p(2).MarkerSize=12;
                hold(app.UIAxes2, 'off');
            end
        end

        % Value changed function: EnvironmentDropDown_2
        function EnvironmentDropDown_2ValueChanged(app, event)
            value = app.EnvironmentDropDown_2.Value;
            switch value
                case 'Land' 
                    app.grav=9.81;
                    
                case 'Underwater' 
                    app.grav=-9.81;
                case 'The Moon'
                    app.grav=9.81/6;
                case '(pick one)'
            end
            
            app.runda=0;
            app.LifeGauge.Value=50;
            app.LifeGauge_2.Value=50;
            app.TextArea_2.Visible='off';
            
            turn=round(rand+1); % losowanie kolejnosci
            if turn==1
                app.Player1Panel.Visible='on';
                app.Player2Panel.Visible='off';
            end
            if turn==2
                app.Player2Panel.Visible='on';
                app.Player1Panel.Visible='off';
            end
            
            NewPoint2(app);
            plot(app.UIAxes2, app.PointX2, app.PointY2, 'g*');
            hold(app.UIAxes2, 'on');
            p=plot(app.UIAxes2, -8, 0, 'rd', 8, 0, 'bd');
            p(1).MarkerSize=12;
            p(2).MarkerSize=12;
            hold(app.UIAxes2, 'off');
        end

        % Value changed function: VelocitySlider
        function VelocitySliderValueChanged(app, event)
            value = app.VelocitySlider.Value;
            if value>0
                app.FIREButton.Text='FIRE!';
                app.FIREButton.Enable='on';
            else
                app.FIREButton.Text='0 velocity';
                app.FIREButton.Enable='off';
            end
        end

        % Callback function
        function VelocitySlider_3ValueChanged(app, event)
            
        end

        % Value changed function: VelocitySlider_2
        function VelocitySlider_2ValueChanged(app, event)
            value = app.VelocitySlider_2.Value;
            if value>0
                app.FIREButton_2.Text='FIRE!';
                app.FIREButton_2.Enable='on';
            else
                app.FIREButton_2.Text='0 velocity';
                app.FIREButton_2.Enable='off';
            end
        end

        % Value changed function: VelocitySlider_3
        function VelocitySlider_3ValueChanged2(app, event)
            value = app.VelocitySlider_3.Value;
            if value>0
                app.FIREButton_3.Text='FIRE!';
                app.FIREButton_3.Enable='on';
            else
                app.FIREButton_3.Text='0 velocity';
                app.FIREButton_3.Enable='off';
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SingleplayerTab = uitab(app.TabGroup);
            app.SingleplayerTab.Title = 'Single player';
            app.VelocitySliderLabel = uilabel(app.SingleplayerTab);
            app.VelocitySliderLabel.Text = 'Velocity';
            app.VelocitySlider = uislider(app.SingleplayerTab);
            app.VelocitySlider.Limits = [0 25];
            app.VelocitySlider.MajorTicks = [0 5 10 15 20 25];
            app.VelocitySlider.ValueChangedFcn = createCallbackFcn(app, @VelocitySliderValueChanged, true);
            app.AngleSliderLabel = uilabel(app.SingleplayerTab);
            app.AngleSliderLabel.Text = 'Angle';
            app.AngleSlider = uislider(app.SingleplayerTab);
            app.AngleSlider.Limits = [0 360];
            app.AngleSlider.MajorTicks = [0 45 90 135 180 225 270 315 360];
            app.EnvironmentDropDownLabel = uilabel(app.SingleplayerTab);
            app.EnvironmentDropDownLabel.Text = 'Environment';
            app.EnvironmentDropDown = uidropdown(app.SingleplayerTab);
            app.EnvironmentDropDown.Items = {'Land', 'Underwater', 'The Moon', 'Void'};
            app.EnvironmentDropDown.ValueChangedFcn = createCallbackFcn(app, @EnvironmentDropDownValueChanged, true);
            app.EnvironmentDropDown.Value = 'Land';
            app.FIREButton = uibutton(app.SingleplayerTab, 'push');
            app.FIREButton.ButtonPushedFcn = createCallbackFcn(app, @FIREButtonPushed, true);
            app.FIREButton.Enable = 'off';
            app.FIREButton.Text = 'FIRE!';
            app.PicknewpointButton = uibutton(app.SingleplayerTab, 'push');
            app.PicknewpointButton.ButtonPushedFcn = createCallbackFcn(app, @PicknewpointButtonPushed, true);
            app.PicknewpointButton.Text = 'Pick new point';
            app.HITLampLabel = uilabel(app.SingleplayerTab);
            app.HITLampLabel.Text = 'HIT';
            app.HITLamp = uilamp(app.SingleplayerTab);
            app.UIAxes = uiaxes(app.SingleplayerTab);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XLim = [-10 10];
            app.UIAxes.YLim = [-10 10];
            app.UIAxes.ZLim = [0 1];
            app.UIAxes.XAxisLocation = 'origin';
            app.UIAxes.YAxisLocation = 'origin';
            app.Background1 = uiimage(app.SingleplayerTab);
            app.Background1.Enable = 'off';
            app.Background1.ImageSource = 'landsurfc.jpg';
            app.TriesEditFieldLabel = uilabel(app.SingleplayerTab);
            app.TriesEditFieldLabel.Text = 'Tries';
            app.TriesEditField = uieditfield(app.SingleplayerTab, 'numeric');
            app.TriesEditField.Limits = [0 Inf];
            app.TriesEditField.Editable = 'off';
            app.TwoplayersTab = uitab(app.TabGroup);
            app.TwoplayersTab.Title = 'Two players';
            app.UIAxes2 = uiaxes(app.TwoplayersTab);
            title(app.UIAxes2, '')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            app.UIAxes2.XLim = [-10 10];
            app.UIAxes2.YLim = [-10 10];
            app.UIAxes2.ZLim = [0 1];
            app.UIAxes2.XAxisLocation = 'origin';
            app.UIAxes2.XTick = [-10 -8 -6 -4 -2 0 2 4 6 8 10];
            app.UIAxes2.YAxisLocation = 'origin';
            app.UIAxes2.YTick = [-10 -8 -6 -4 -2 0 2 4 6 8 10];
            app.EnvironmentDropDown_2Label = uilabel(app.TwoplayersTab);
            app.EnvironmentDropDown_2Label.Text = 'Environment';
            app.EnvironmentDropDown_2 = uidropdown(app.TwoplayersTab);
            app.EnvironmentDropDown_2.Items = {'(pick one)', 'Land', 'Underwater', 'The Moon'};
            app.EnvironmentDropDown_2.ValueChangedFcn = createCallbackFcn(app, @EnvironmentDropDown_2ValueChanged, true);
            app.EnvironmentDropDown_2.Value = '(pick one)';
            app.Player1Panel = uipanel(app.TwoplayersTab);
            app.Player1Panel.Title = 'Player 1';
            app.Player1Panel.Visible = 'off';
            app.FIREButton_2 = uibutton(app.Player1Panel, 'push');
            app.FIREButton_2.ButtonPushedFcn = createCallbackFcn(app, @FIREButton_2Pushed, true);
            app.FIREButton_2.Enable = 'off';
            app.FIREButton_2.Text = 'FIRE!';
            app.VelocitySlider_2Label = uilabel(app.Player1Panel);
            app.VelocitySlider_2Label.Text = 'Velocity';
            app.VelocitySlider_2 = uislider(app.Player1Panel);
            app.VelocitySlider_2.Limits = [0 20];
            app.VelocitySlider_2.MajorTicks = [0 5 10 15 20 25];
            app.VelocitySlider_2.ValueChangedFcn = createCallbackFcn(app, @VelocitySlider_2ValueChanged, true);
            app.AngleSlider_2Label = uilabel(app.Player1Panel);
            app.AngleSlider_2Label.Text = 'Angle';
            app.AngleSlider_2 = uislider(app.Player1Panel);
            app.AngleSlider_2.Limits = [-90 90];
            app.AngleSlider_2.MajorTicks = [-90 -45 0 45 90];
            app.Player2Panel = uipanel(app.TwoplayersTab);
            app.Player2Panel.Title = 'Player 2';
            app.Player2Panel.Visible = 'off';
            app.VelocitySlider_3Label = uilabel(app.Player2Panel);
            app.VelocitySlider_3Label.Text = 'Velocity';
            app.VelocitySlider_3 = uislider(app.Player2Panel);
            app.VelocitySlider_3.Limits = [0 20];
            app.VelocitySlider_3.MajorTicks = [0 5 10 15 20 25];
            app.VelocitySlider_3.ValueChangedFcn = createCallbackFcn(app, @VelocitySlider_3ValueChanged2, true);
            app.AngleSlider_3Label = uilabel(app.Player2Panel);
            app.AngleSlider_3Label.Text = 'Angle';
            app.AngleSlider_3 = uislider(app.Player2Panel);
            app.AngleSlider_3.Limits = [-90 90];
            app.AngleSlider_3.MajorTicks = [-90 -45 0 45 90];
            app.FIREButton_3 = uibutton(app.Player2Panel, 'push');
            app.FIREButton_3.ButtonPushedFcn = createCallbackFcn(app, @FIREButton_3Pushed, true);
            app.FIREButton_3.Enable = 'off';
            app.FIREButton_3.Text = 'FIRE!';
            app.TextArea_2 = uitextarea(app.TwoplayersTab);
            app.TextArea_2.Editable = 'off';
            app.TextArea_2.Visible = 'off';
            app.LifeGaugeLabel = uilabel(app.TwoplayersTab);
            app.LifeGaugeLabel.Text = 'Life';
            app.LifeGauge = uigauge(app.TwoplayersTab, 'semicircular');
            app.LifeGauge.Limits = [0 50];
            app.LifeGauge.Value = 50;
            app.LifeGauge_2Label = uilabel(app.TwoplayersTab);
            app.LifeGauge_2Label.Text = 'Life';
            app.LifeGauge_2 = uigauge(app.TwoplayersTab, 'semicircular');
            app.LifeGauge_2.Limits = [0 50];
            app.LifeGauge_2.Value = 50;
            app.RulesTab = uitab(app.TabGroup);
            app.RulesTab.Title = 'Rules';
            app.TextArea = uitextarea(app.RulesTab);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'Single player'; 'Gracz ma za zadanie trafić do wylosowanego wcześniej punktu, sterując '; 'odpowiednio prędkością oraz kątem. Jeżeli trafi w punkt, to zapali się zielona lampka (HIT); u dołu podany jest również licznik prób. Po trafieniu w punkt gracz może '; 'wylosować nowy punkt za pomocą przycisku "Pick new point".  Jeżeli pocisk znajdzie'; 'się na wysokości większej od +/- 12m, to lot jest przerywany. Gracz ma do wyboru'; 'także kilka środowisk, w których może grać. Zmienia się wówczas wtedy zarówno tło,'; 'jak i stała grawitacji. Do wyboru są 4 lokacje: ląd, woda, Księżyc oraz próżnia, które'; 'może zmieniać w dowolnym momencie gry.'; ''; 'Two players'; 'Podobnie jak w trybie Single Player, gracze mają możliwość wyboru środowiska,'; 'w którym chcą odbywać pojedynek, jednak jego zmiana oznacza powrót do stanu'; 'początkowego. Na początku losowany jest zawodnik, który rozpoczyna rozgrywkę.'; 'Graczom przydzielane są punkty życia oraz symbol ich reprezentujący'; '(odpowiednio dla gracza 1 - czerwony diament, dla drugiego - niebieski diament).'; 'Zadaniem obu z graczy jest trafienie w przeciwnika, oznaczonego przez odpowiedni symbol. Im dokładniejszy strzał, tym więcej zadamy punktów obrażeń. Dodatkowo'; 'trafienie w zielony punkt będzie oznaczać bonus do zadanych obrażeń. Zwycięzcą'; 'jest osoba, która odbierze [rzeciwnikowi wszystkie punkty życia. Jednak, aby gra nie'; 'była monotonna, od 3. rundy parametry (tj. prędkość i kąt) zostają ustawione '; 'na wartości domyślne. '; 'Powodzenia!'; ''; ''; 'Made by Bartosz Rogowski'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PROJEKT

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end